classdef BoardPlayingRobot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        EmergencyStopSwitchLabel  matlab.ui.control.Label
        EmergencyStopSwitch       matlab.ui.control.Switch
        CollisionsSwitchLabel     matlab.ui.control.Label
        CollisionsSwitch          matlab.ui.control.Switch
        YSpinnerLabel             matlab.ui.control.Label
        YSpinner                  matlab.ui.control.Spinner
        XSpinnerLabel             matlab.ui.control.Label
        XSpinner                  matlab.ui.control.Spinner
        ZSpinnerLabel             matlab.ui.control.Label
        ZSpinner                  matlab.ui.control.Spinner
        PlayerDoneButton          matlab.ui.control.Button
        q1EditFieldLabel          matlab.ui.control.Label
        q1EditField               matlab.ui.control.NumericEditField
        q2EditFieldLabel          matlab.ui.control.Label
        q2EditField               matlab.ui.control.NumericEditField
        q3EditFieldLabel          matlab.ui.control.Label
        q3EditField               matlab.ui.control.NumericEditField
        q4EditFieldLabel          matlab.ui.control.Label
        q4EditField               matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        uArm
        emergency = false;
        collisions = false;
        workspace = [-1 1 -1 1 -1 1];
        scale = 1;
    end

    methods (Access = private)
    
        function createUArm(app)
            L1 = Link('d',0,'a',.2,'alpha',pi/2,'offset',0,'qlim', [-pi/2,pi/2]);
            L2 = Link('d',0,'a',.15,'alpha',0,'offset',pi/1.5,'qlim', [(-2/3*pi),(1/3*pi)]);
            L3 = Link('d',0,'a',.16,'alpha',0,'offset',-pi/1.5,'qlim', [-pi/3,(2/3*pi)]);
            L4 = Link('d',0,'a',.0695,'alpha',-pi/2,'offset',degtorad(-59.7),'qlim',  [-pi/3,pi/3]);
        
            app.uArm = SerialLink([L1 L2 L3 L4], 'name', 'uArm');
        end
        
        function plot(app, qValues)
            app.uArm.plot(qValues, 'workspace', app.workspace, 'scale', app.scale);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.createUArm();
        end

        % Value changed function: CollisionsSwitch
        function CollisionsSwitchValueChanged(app, event)
            value = app.CollisionsSwitch.Value;
            
            if strcmp(value, 'On')
                display('Collissions have been activated');
                app.YSpinner.Value = 10;
            else
                display('Collissions have been deactivated');
                app.YSpinner.Value = 5;
            end
                
        end

        % Value changed function: EmergencyStopSwitch
        function EmergencyStopSwitchValueChanged(app, event)
            value = app.EmergencyStopSwitch.Value;
            
            if strcmp(value, 'On')
                display('Emergency stop has been activated');
            else
                display('Emergency stop has been deactivated');
            end
        end

        % Button pushed function: PlayerDoneButton
        function PlayerDoneButtonPushed(app, event)
            app.uArm.plot([0 0 0 0], 'workspace', app.workspace, 'scale', app.scale);
        end

        % Value changed function: q1EditField, q2EditField, 
        % q3EditField, q4EditField
        function editFieldValueChanged(app, event)
            q1 = app.q1EditField.Value;
            q2 = app.q2EditField.Value;
            q3 = app.q3EditField.Value;
            q4 = app.q4EditField.Value;
            
            app.plot([q1 q2 q3 q4]);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.EmergencyStopSwitchLabel = uilabel(app.UIFigure);
            app.EmergencyStopSwitchLabel.Text = 'Emergency Stop';
            app.EmergencyStopSwitch = uiswitch(app.UIFigure, 'slider');
            app.EmergencyStopSwitch.ValueChangedFcn = createCallbackFcn(app, @EmergencyStopSwitchValueChanged, true);
            app.CollisionsSwitchLabel = uilabel(app.UIFigure);
            app.CollisionsSwitchLabel.Text = 'Collisions';
            app.CollisionsSwitch = uiswitch(app.UIFigure, 'slider');
            app.CollisionsSwitch.ValueChangedFcn = createCallbackFcn(app, @CollisionsSwitchValueChanged, true);
            app.YSpinnerLabel = uilabel(app.UIFigure);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.UIFigure);
            app.XSpinnerLabel = uilabel(app.UIFigure);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.UIFigure);
            app.ZSpinnerLabel = uilabel(app.UIFigure);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.UIFigure);
            app.PlayerDoneButton = uibutton(app.UIFigure, 'push');
            app.PlayerDoneButton.ButtonPushedFcn = createCallbackFcn(app, @PlayerDoneButtonPushed, true);
            app.PlayerDoneButton.Text = 'Player Done';
            app.q1EditFieldLabel = uilabel(app.UIFigure);
            app.q1EditFieldLabel.Text = 'q1';
            app.q1EditField = uieditfield(app.UIFigure, 'numeric');
            app.q1EditField.ValueChangedFcn = createCallbackFcn(app, @editFieldValueChanged, true);
            app.q2EditFieldLabel = uilabel(app.UIFigure);
            app.q2EditFieldLabel.Text = 'q2';
            app.q2EditField = uieditfield(app.UIFigure, 'numeric');
            app.q2EditField.ValueChangedFcn = createCallbackFcn(app, @editFieldValueChanged, true);
            app.q3EditFieldLabel = uilabel(app.UIFigure);
            app.q3EditFieldLabel.Text = 'q3';
            app.q3EditField = uieditfield(app.UIFigure, 'numeric');
            app.q3EditField.ValueChangedFcn = createCallbackFcn(app, @editFieldValueChanged, true);
            app.q4EditFieldLabel = uilabel(app.UIFigure);
            app.q4EditFieldLabel.Text = 'q4';
            app.q4EditField = uieditfield(app.UIFigure, 'numeric');
            app.q4EditField.ValueChangedFcn = createCallbackFcn(app, @editFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = BoardPlayingRobot

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end